classdef ABRcontrol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ABRControlPanelUIFigure        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadConfigurationMenu          matlab.ui.container.Menu
        SaveConfigurationMenu          matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        StayonTopMenu                  matlab.ui.container.Menu
        AcquisitionFilterDesignMenu    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        ConfigTab                      matlab.ui.container.Tab
        ScheduleDropDownLabel          matlab.ui.control.Label
        ConfigScheduleDropDown         matlab.ui.control.DropDown
        ConfigLocateButton             matlab.ui.control.Button
        ConfigNewButton                matlab.ui.control.Button
        OutputDropDownLabel            matlab.ui.control.Label
        ConfigOutputDropDown           matlab.ui.control.DropDown
        ConfigSaveButton               matlab.ui.control.Button
        ConfigLoadButton               matlab.ui.control.Button
        SubjectInfoTab                 matlab.ui.container.Tab
        DOBDatePickerLabel             matlab.ui.control.Label
        SubjectDOBDatePicker           matlab.ui.control.DatePicker
        SubjectTree                    matlab.ui.container.Tree
        NotesTextAreaLabel             matlab.ui.control.Label
        SubjectNotesTextArea           matlab.ui.control.TextArea
        AliasEditFieldLabel            matlab.ui.control.Label
        SubjectAliasEditField          matlab.ui.control.EditField
        IDEditFieldLabel               matlab.ui.control.Label
        SubjectIDEditField             matlab.ui.control.EditField
        SubjectSexSwitch               matlab.ui.control.Switch
        ScientistDropDownLabel         matlab.ui.control.Label
        SubjectScientistDropDown       matlab.ui.control.DropDown
        SubjectdirectoryButton         matlab.ui.control.Button
        ControlTab                     matlab.ui.container.Tab
        ControlSweepCountGauge         matlab.ui.control.LinearGauge
        Panel                          matlab.ui.container.Panel
        AdvancementCriteriaDropDownLabel  matlab.ui.control.Label
        SweepAdvancementCriteriaDropDown  matlab.ui.control.DropDown
        SweepsSpinnerLabel             matlab.ui.control.Label
        SweepCountSpinner              matlab.ui.control.Spinner
        SweepRateHzSpinnerLabel        matlab.ui.control.Label
        SweepRateHzSpinner             matlab.ui.control.Spinner
        Panel_2                        matlab.ui.container.Panel
        ControlAdvanceButton           matlab.ui.control.Button
        ControlRepeatButton            matlab.ui.control.StateButton
        ControlPauseButton             matlab.ui.control.StateButton
        ControlAcquireIdleSwitch       matlab.ui.control.ToggleSwitch
        ControlAcquireLamp             matlab.ui.control.Lamp
        UtilitiesTab                   matlab.ui.container.Tab
        UtilityScheduleDesignButton    matlab.ui.control.Button
        UtilitySoundCalibrationButton  matlab.ui.control.Button
        UtilityABRDataViewerButton     matlab.ui.control.Button
        UtilityOnlineAnalysisButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        SubjectNode
    end
    
    methods (Access = private)
        
        function createSubjectTree(app)
            subjs = dir(fullfile(app.subjectDir,'*.ABRsubj'));
            
            if isfield(app,'SubjectNode'), delete(app.SubjectNode); end
            
            for i = 1:length(subjs)
                
                load(fullfile(subjs.folder,subjs.name),'Subject');
                
                % Create Node
                app.SubjectNode(i) = uitreenode(app.SubjectTree);
                app.SubjectNode(i).Text = sprintf('%s: %s',Subject.ID,Subject.Alias);
                
                
            end
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: UtilityScheduleDesignButton
        function ScheduleDesign(app, event)
            % Launch Schedule Design utility or locate if already exists
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ABRControlPanelUIFigure = uifigure;
            app.ABRControlPanelUIFigure.Name = 'ABR Control Panel';
            app.FileMenu = uimenu(app.ABRControlPanelUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadConfigurationMenu = uimenu(app.FileMenu);
            app.LoadConfigurationMenu.Text = 'Load Configuration ...';
            app.SaveConfigurationMenu = uimenu(app.FileMenu);
            app.SaveConfigurationMenu.Text = 'Save Configuration ...';
            app.OptionsMenu = uimenu(app.ABRControlPanelUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.StayonTopMenu = uimenu(app.OptionsMenu);
            app.StayonTopMenu.Text = 'Stay on Top';
            app.AcquisitionFilterDesignMenu = uimenu(app.OptionsMenu);
            app.AcquisitionFilterDesignMenu.Text = 'Acquisition Filter Design';
            app.TabGroup = uitabgroup(app.ABRControlPanelUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.ScheduleDropDownLabel = uilabel(app.ConfigTab);
            app.ScheduleDropDownLabel.Text = 'Schedule';
            app.ConfigScheduleDropDown = uidropdown(app.ConfigTab);
            app.ConfigScheduleDropDown.Items = {'schedule_file.sched'};
            app.ConfigScheduleDropDown.Editable = 'on';
            app.ConfigScheduleDropDown.Value = 'schedule_file.sched';
            app.ConfigLocateButton = uibutton(app.ConfigTab, 'push');
            app.ConfigLocateButton.Text = 'locate';
            app.ConfigNewButton = uibutton(app.ConfigTab, 'push');
            app.ConfigNewButton.Text = 'new';
            app.OutputDropDownLabel = uilabel(app.ConfigTab);
            app.OutputDropDownLabel.Text = 'Output';
            app.ConfigOutputDropDown = uidropdown(app.ConfigTab);
            app.ConfigOutputDropDown.Items = {'data_output_file.abr'};
            app.ConfigOutputDropDown.Editable = 'on';
            app.ConfigOutputDropDown.Value = 'data_output_file.abr';
            app.ConfigSaveButton = uibutton(app.ConfigTab, 'push');
            app.ConfigSaveButton.Text = 'Save';
            app.ConfigLoadButton = uibutton(app.ConfigTab, 'push');
            app.ConfigLoadButton.Text = 'Load';
            app.SubjectInfoTab = uitab(app.TabGroup);
            app.SubjectInfoTab.Title = 'Subject Info';
            app.DOBDatePickerLabel = uilabel(app.SubjectInfoTab);
            app.DOBDatePickerLabel.Text = 'DOB';
            app.SubjectDOBDatePicker = uidatepicker(app.SubjectInfoTab);
            app.SubjectTree = uitree(app.SubjectInfoTab);
            app.NotesTextAreaLabel = uilabel(app.SubjectInfoTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.SubjectNotesTextArea = uitextarea(app.SubjectInfoTab);
            app.AliasEditFieldLabel = uilabel(app.SubjectInfoTab);
            app.AliasEditFieldLabel.Text = 'Alias';
            app.SubjectAliasEditField = uieditfield(app.SubjectInfoTab, 'text');
            app.IDEditFieldLabel = uilabel(app.SubjectInfoTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.SubjectIDEditField = uieditfield(app.SubjectInfoTab, 'text');
            app.SubjectSexSwitch = uiswitch(app.SubjectInfoTab, 'slider');
            app.SubjectSexSwitch.Items = {'Female', 'Male'};
            app.SubjectSexSwitch.Tooltip = {'Select subject sex'};
            app.SubjectSexSwitch.Value = 'Female';
            app.ScientistDropDownLabel = uilabel(app.SubjectInfoTab);
            app.ScientistDropDownLabel.Text = 'Scientist';
            app.SubjectScientistDropDown = uidropdown(app.SubjectInfoTab);
            app.SubjectdirectoryButton = uibutton(app.SubjectInfoTab, 'push');
            app.SubjectdirectoryButton.Tooltip = {'Select subject directory'};
            app.SubjectdirectoryButton.Text = 'Subject directory ...';
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.ControlSweepCountGauge = uigauge(app.ControlTab, 'linear');
            app.Panel = uipanel(app.ControlTab);
            app.AdvancementCriteriaDropDownLabel = uilabel(app.Panel);
            app.AdvancementCriteriaDropDownLabel.Text = 'Advancement Criteria';
            app.SweepAdvancementCriteriaDropDown = uidropdown(app.Panel);
            app.SweepAdvancementCriteriaDropDown.Items = {'# Sweeps', 'Correlation Threshold', ''};
            app.SweepAdvancementCriteriaDropDown.Value = '# Sweeps';
            app.SweepsSpinnerLabel = uilabel(app.Panel);
            app.SweepsSpinnerLabel.Text = '# Sweeps';
            app.SweepCountSpinner = uispinner(app.Panel);
            app.SweepCountSpinner.Limits = [1 Inf];
            app.SweepCountSpinner.RoundFractionalValues = 'on';
            app.SweepCountSpinner.ValueDisplayFormat = '%d';
            app.SweepCountSpinner.Value = 1024;
            app.SweepRateHzSpinnerLabel = uilabel(app.Panel);
            app.SweepRateHzSpinnerLabel.Text = 'Sweep Rate (Hz)';
            app.SweepRateHzSpinner = uispinner(app.Panel);
            app.SweepRateHzSpinner.Limits = [0.001 100];
            app.SweepRateHzSpinner.Value = 21.1;
            app.Panel_2 = uipanel(app.ControlTab);
            app.ControlAdvanceButton = uibutton(app.Panel_2, 'push');
            app.ControlAdvanceButton.Text = 'Advance >';
            app.ControlRepeatButton = uibutton(app.Panel_2, 'state');
            app.ControlRepeatButton.Text = 'Repeat';
            app.ControlPauseButton = uibutton(app.Panel_2, 'state');
            app.ControlPauseButton.Text = 'Pause ||';
            app.ControlAcquireIdleSwitch = uiswitch(app.Panel_2, 'toggle');
            app.ControlAcquireIdleSwitch.Items = {'Idle', 'Acquire'};
            app.ControlAcquireIdleSwitch.Value = 'Idle';
            app.ControlAcquireLamp = uilamp(app.Panel_2);
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.UtilityScheduleDesignButton = uibutton(app.UtilitiesTab, 'push');
            app.UtilityScheduleDesignButton.ButtonPushedFcn = createCallbackFcn(app, @ScheduleDesign, true);
            app.UtilityScheduleDesignButton.Text = 'Schedule Design';
            app.UtilitySoundCalibrationButton = uibutton(app.UtilitiesTab, 'push');
            app.UtilitySoundCalibrationButton.Text = 'Sound Calibration';
            app.UtilityABRDataViewerButton = uibutton(app.UtilitiesTab, 'push');
            app.UtilityABRDataViewerButton.Text = 'ABR Data Viewer';
            app.UtilityOnlineAnalysisButton = uibutton(app.UtilitiesTab, 'push');
            app.UtilityOnlineAnalysisButton.Text = 'Online Analysis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ABRcontrol

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ABRControlPanelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ABRControlPanelUIFigure)
        end
    end
end