classdef Calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveCalibrationDataMenu         matlab.ui.container.Menu
        LoadCalibrationDataMenu         matlab.ui.container.Menu
        StimulusPanel                   matlab.ui.container.Panel
        DefineButton                    matlab.ui.control.Button
        HardwarePanel                   matlab.ui.container.Panel
        AudioDeviceDropDownLabel        matlab.ui.control.Label
        AudioDeviceDropDown             matlab.ui.control.DropDown
        SamplingRateDropDownLabel       matlab.ui.control.Label
        SamplingRateDropDown            matlab.ui.control.DropDown
        CalibrationPanel                matlab.ui.container.Panel
        LocatePlotsButton               matlab.ui.control.Button
        RunCalibrationSwitch            matlab.ui.control.RockerSwitch
        NoteTextAreaLabel               matlab.ui.control.Label
        NoteTextArea                    matlab.ui.control.TextArea
        ReferencePanel                  matlab.ui.container.Panel
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        SoundLeveldBSPLEditFieldLabel   matlab.ui.control.Label
        SoundLeveldBSPLEditField        matlab.ui.control.NumericEditField
        MeasuredVoltageVEditFieldLabel  matlab.ui.control.Label
        MeasuredVoltageVEditField       matlab.ui.control.NumericEditField
        SampleButton                    matlab.ui.control.Button
        StateLamp                       matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        C               abr.AcousticCalibration = abr.AcousticCalibration;
        playRecObj      audioPlayerRecorder = audioPlayerRecorder;
        STATE           {mustBeMember(STATE,{'setup','idle','prerun','running','postrun','error','usercancelled'})} = 'idle';
        frameSize       (1,1) double = 1024;
        lastError   % me
        
        SchedDesign
        
        responsePlot
    end
    
    methods
        
        function set.STATE(app,newState)
            h = app.StateLamp;
            
            h.Color = [0.8 0.8 0.8];
            
            switch newState
                case 'setup'
                    h.Tooltip = 'Starting';
                    h.Color = [1 1 0];
                    
                case 'idle'
                    h.Tooltip = '';
                    
                case 'prerun'
                    h.Tooltip = 'Prepping';
                    h.Color = [1 1 0];
                    
                case 'running'
                    h.Tooltip = 'Running';
                    h.Color = [0.2 1 0.2];
                    
                case 'postrun'
                    h.Tooltip = 'Done';
                    
                case 'error'
                    %                     h.Tooltip = app.lastError.message;
                    h.Color = [1 0 0];
                    
                case 'usercancelled'
                    h.Tooltip = 'Cancelled';
            end
            
            drawnow
        end
        
    end
    
    methods (Access = private)
        
        function gather_parameters(app)
            app.C.Device        = app.AudioDeviceDropDown.Value;
            app.C.SampleRate    = str2double(app.SamplingRateDropDown.Value);
            
            app.C.ReferenceFreq = app.FrequencyHzEditField.Value;
            app.C.ReferenceSPL  = app.SoundLeveldBSPLEditField.Value;
            app.C.ReferenceV    = app.MeasuredVoltageVEditField.Value;
            
            app.C.Note = app.NoteTextArea.Value;
            
        end


        function setup_playRecObj(app)
            app.STATE = 'prerun';
            
            app.gather_parameters;
            
            if ishandle(app.playRecObj)
                reset(app.playRecObj);
            else
                app.playRecObj = audioPlayerRecorder;
            end
            
            app.playRecObj.Device       = app.C.Device;
            app.playRecObj.SampleRate   = app.C.SampleRate;
            
            app.C.DeviceInfo = app.playRecObj.info;
            
            app.C.DAC.FrameSize = app.frameSize;
            app.C.ADC.FrameSize = app.frameSize;
            
        end


        function trigger_playrec(app)
            bidx = 1:app.frameSize:app.C.DAC.N;
            for i = 1:length(bidx)-1
                idx = bidx(i):bidx(i+1)-1;
                app.C.ADC.Data(idx) = app.playRecObj(app.C.DAC.Data(idx));
            end
        end


        function update_response_plot(app)
            app.responsePlot.figure = findobj('type','figure','-and','name','Response');
            if isempty(app.responsePlot.figure)
                app.responsePlot.figure = figure('name','Response','Color','w','Position',[300 300 600 400]);
            end
            f = app.responsePlot.figure;
            
            figure(f);
            
            % time domain plot
            ax = subplot(211,'Parent',f);
            app.C.ADC.plotSweeps(ax);
            hold(ax,'on');
            app.C.ADC.plotMean(ax);
            hold(ax,'off');
            
            % freq domain plot
            ax = subplot(212,'Parent',f);
            app.C.ADC.plotFFT(ax);
            ax.XScale = 'log';
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STATE = 'setup';
            
            app.playRecObj = audioPlayerRecorder;
            
            devices = app.playRecObj.getAudioDevices;
            lastused = getpref('AcousticCalibration','audioDevice',devices{1});
            ind = ismember(devices,lastused);
            if any(ind)
                lastused = devices{ind};
            else
                ind = contains(devices,'ASIO','IgnoreCase',true);
                if any(ind)
                    lastused = devices{ind};
                else
                    lastused = devices{1};
                end
            end
            app.AudioDeviceDropDown.Items = devices;
            app.AudioDeviceDropDown.Value = lastused;
            
            lastused = getpref('AcousticCalibration','samplingRate','44100');
            app.SamplingRateDropDown.Value = lastused;
            
            app.STATE = 'idle';
        end

        % Close request function: CalibrationFigure
        function CalibrationFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: RunCalibrationSwitch
        function RunCalibrationSwitchValueChanged(app, event)
            value = app.RunCalibrationSwitch.Value;
            switch value
                case 'Run'                    
                    app.setup_playRecObj;
                    
                    app.STATE = 'running';
                    
                    
                    % initialize Buffers
                    app.C.DAC.Data = zeros(app.C.DAC.SampleRate,1);
                    app.C.DAC.SweepLength = app.C.DAC.N; % same in this case
                    app.C.DAC.SweepOnsets = 1; % single acquisition
                    
                    app.C.ADC.Data = zeros(app.C.ADC.SampleRate,1);
                    app.C.ADC.SweepLength = app.C.ADC.N; % same in this case
                    app.C.ADC.SweepOnsets = 1; % single acquisition
                    
                    app.STATE = 'running';
                    
                    app.trigger_playrec;
                    
                    % plot time & freq representations of ADC
                    app.update_response_plot;
                    
                    app.STATE = 'postrun';
                    
                    app.SaveCalibrationDataMenuSelected;
                    
                case 'Idle'
                    app.STATE = 'usercancelled';
            end
        end

        % Value changed function: AudioDeviceDropDown
        function AudioDeviceDropDownValueChanged(app, event)
            value = app.AudioDeviceDropDown.Value;
            setpref('AcousticCalibration','audioDevice',value);
        end

        % Value changed function: SamplingRateDropDown
        function SamplingRateDropDownValueChanged(app, event)
            value = app.SamplingRateDropDown.Value;
            setpref('AcousticCalibration','samplingRate',value);
        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
            try
                app.setup_playRecObj;
                
                % initialize Buffers
                app.C.DAC.Data = zeros(app.C.DAC.SampleRate,1);
                app.C.DAC.SweepLength = app.C.DAC.N; % same in this case
                app.C.DAC.SweepOnsets = 1; % single acquisition
                
                app.C.ADC.Data = zeros(app.C.ADC.SampleRate,1);
                app.C.ADC.SweepLength = app.C.ADC.N; % same in this case
                app.C.ADC.SweepOnsets = 1; % single acquisition
                
                app.STATE = 'running';
                
                app.trigger_playrec;
                
                % plot time & freq representations of ADC
                app.update_response_plot;
                
                app.STATE = 'postrun';
                
            catch me
                app.STATE = 'error';
                app.lastError = me;
                rethrow(me); % handle error message
            end
            
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            app.RunCalibrationSwitch.Enable = 'off'; drawnow
            S = ScheduleDesign;
            
            waitfor(S.CompileButton,'UserData'); % Must hit compile button to continue
            
            app.C.SIG = S.SIG;
            app.RunCalibrationSwitch.Enable = 'on';
        end

        % Menu selected function: SaveCalibrationDataMenu
        function SaveCalibrationDataMenuSelected(app, event)
            dfltpn = getpref('AcousticCalibration','dataPath',cd);
            [pn,fn] = uiputfile({'*.cal','Calibration (*.cal)'}, ...
                'Save Calibration Data',dfltpn);
            
            if isequal(fn,0), return; end
            
            ffn = fullfile(pn,fn);
            
            CalibrationData = app.C;
            
            CalibrationData.Timestamp = datestr(now);
            
            save(ffn,'CalibrationData','-mat');
            
            setpref('AcousticCalibration','dataPath',pn);
        end

        % Menu selected function: LoadCalibrationDataMenu
        function LoadCalibrationDataMenuSelected(app, event)
            dfltpn = getpref('AcousticCalibration','dataPath',cd);
            [pn,fn] = uigetfile({'*.cal','Calibration (*.cal)'}, ...
                'Load Calibration Data', ...
                dfltpn,'MultiSelect','off');
            
            if isequal(fn,0), return; end
            
            ffn = fullfile(pn,fn);
            
            fprintf('Loading %s\n',ffn)
            
            load(ffn,'CalibrationData','-mat');
            
            app.C = CalibrationData;
            
            fprintf('\tCalibration from: %s\n',app.C.Timestamp);
            
            setpref('AcousticCalibration','dataPath',pn);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationFigure = uifigure;
            app.CalibrationFigure.Name = 'UI Figure';
            app.CalibrationFigure.CloseRequestFcn = createCallbackFcn(app, @CalibrationFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CalibrationFigure);
            app.FileMenu.Text = 'File';
            app.SaveCalibrationDataMenu = uimenu(app.FileMenu);
            app.SaveCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCalibrationDataMenuSelected, true);
            app.SaveCalibrationDataMenu.Accelerator = 'S';
            app.SaveCalibrationDataMenu.Text = 'Save Calibration Data';
            app.LoadCalibrationDataMenu = uimenu(app.FileMenu);
            app.LoadCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCalibrationDataMenuSelected, true);
            app.LoadCalibrationDataMenu.Accelerator = 'L';
            app.LoadCalibrationDataMenu.Text = 'Load Calibration Data';
            app.StimulusPanel = uipanel(app.CalibrationFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.DefineButton = uibutton(app.StimulusPanel, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.HardwarePanel = uipanel(app.CalibrationFigure);
            app.HardwarePanel.Title = 'Hardware';
            app.AudioDeviceDropDownLabel = uilabel(app.HardwarePanel);
            app.AudioDeviceDropDownLabel.Text = 'Audio Device';
            app.AudioDeviceDropDown = uidropdown(app.HardwarePanel);
            app.AudioDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDeviceDropDownValueChanged, true);
            app.SamplingRateDropDownLabel = uilabel(app.HardwarePanel);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.HardwarePanel);
            app.SamplingRateDropDown.Items = {'44100 Hz', '48000 Hz', '96000 Hz', '176400 Hz', '192000 Hz'};
            app.SamplingRateDropDown.ItemsData = {'44100', '48000', '96000', '176400', '192000'};
            app.SamplingRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingRateDropDownValueChanged, true);
            app.SamplingRateDropDown.Tooltip = {'Not all sampling rates  may be supported by your sound card.'};
            app.SamplingRateDropDown.Value = '44100';
            app.CalibrationPanel = uipanel(app.CalibrationFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.LocatePlotsButton = uibutton(app.CalibrationPanel, 'push');
            app.LocatePlotsButton.Text = 'Locate Plots';
            app.RunCalibrationSwitch = uiswitch(app.CalibrationPanel, 'rocker');
            app.RunCalibrationSwitch.Items = {'Idle', 'Run'};
            app.RunCalibrationSwitch.ValueChangedFcn = createCallbackFcn(app, @RunCalibrationSwitchValueChanged, true);
            app.RunCalibrationSwitch.Enable = 'off';
            app.RunCalibrationSwitch.Tooltip = {'Must sample a reference tone and define a stimulus before calibration.'};
            app.RunCalibrationSwitch.Value = 'Idle';
            app.NoteTextAreaLabel = uilabel(app.CalibrationPanel);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.CalibrationPanel);
            app.ReferencePanel = uipanel(app.CalibrationFigure);
            app.ReferencePanel.Title = 'Reference';
            app.FrequencyHzEditFieldLabel = uilabel(app.ReferencePanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.FrequencyHzEditField.Limits = [1 1000000];
            app.FrequencyHzEditField.Value = 1000;
            app.SoundLeveldBSPLEditFieldLabel = uilabel(app.ReferencePanel);
            app.SoundLeveldBSPLEditFieldLabel.Text = 'Sound Level (dB SPL)';
            app.SoundLeveldBSPLEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.SoundLeveldBSPLEditField.Limits = [1 1000000];
            app.SoundLeveldBSPLEditField.Value = 114;
            app.MeasuredVoltageVEditFieldLabel = uilabel(app.ReferencePanel);
            app.MeasuredVoltageVEditFieldLabel.Text = 'Measured Voltage (V)';
            app.MeasuredVoltageVEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.MeasuredVoltageVEditField.LowerLimitInclusive = 'off';
            app.MeasuredVoltageVEditField.Limits = [0 10];
            app.MeasuredVoltageVEditField.Value = 0.1;
            app.SampleButton = uibutton(app.ReferencePanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.StateLamp = uilamp(app.CalibrationFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationFigure)
        end
    end
end